classdef myapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        PatientInformationPanel      matlab.ui.container.Panel
        EmailIDEditField             matlab.ui.control.EditField
        EmailIDEditFieldLabel        matlab.ui.control.Label
        DateDatePicker               matlab.ui.control.DatePicker
        DateDatePickerLabel          matlab.ui.control.Label
        SexDropDown                  matlab.ui.control.DropDown
        SexDropDownLabel             matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        ImageAnalysisPanel           matlab.ui.container.Panel
        StopFeedButton               matlab.ui.control.Button
        StartFeedButton              matlab.ui.control.Button
        ResultsPanel                 matlab.ui.container.Panel
        PlatletsCountEditField       matlab.ui.control.NumericEditField
        PlatletsCountEditFieldLabel  matlab.ui.control.Label
        WBCCountEditField            matlab.ui.control.NumericEditField
        WBCCountEditFieldLabel       matlab.ui.control.Label
        RBCCountEditField            matlab.ui.control.NumericEditField
        RBCCountEditFieldLabel       matlab.ui.control.Label
        EmailButton                  matlab.ui.control.Button
        AnalyseButton                matlab.ui.control.Button
        CaptureButton                matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        CameraConfigurationPanel     matlab.ui.container.Panel
        MeteringModeDropDown         matlab.ui.control.DropDown
        MeteringModeLabel            matlab.ui.control.Label
        AWBModeDropDown              matlab.ui.control.DropDown
        AWBModeLabel                 matlab.ui.control.Label
        ExposureModeDropDown         matlab.ui.control.DropDown
        ExposureModeLabel            matlab.ui.control.Label
        ExposureCompensationSlider   matlab.ui.control.Slider
        ExposureCompensationSliderLabel  matlab.ui.control.Label
        VerticalFlipSwitch           matlab.ui.control.Switch
        VerticalFlipSwitchLabel      matlab.ui.control.Label
        SharpnessSlider              matlab.ui.control.Slider
        SharpnessSliderLabel         matlab.ui.control.Label
        SaturationSlider             matlab.ui.control.Slider
        SaturationSliderLabel        matlab.ui.control.Label
        ContrastSlider               matlab.ui.control.Slider
        ContrastSliderLabel          matlab.ui.control.Label
        BrightnessSlider             matlab.ui.control.Slider
        BrightnessSliderLabel        matlab.ui.control.Label
        HorizontalFlipSwitch         matlab.ui.control.Switch
        HorizontalFlipSwitchLabel    matlab.ui.control.Label
        RotationDropDown             matlab.ui.control.DropDown
        RotationDropDownLabel        matlab.ui.control.Label
        QualitySlider                matlab.ui.control.Slider
        QualitySliderLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        EmailID
        Image
        RaspiObj
        CameraObj
        TimerObj
    end

    methods (Access = public)

        function createCameraObj(app)
            app.CameraObj = [];
            clear app.CameraObj;

            if isempty(app.CameraObj)
                app.CameraObj = cameraboard(app.RaspiObj,'Quality',floor(app.QualitySlider.Value), 'Brightness',floor(app.BrightnessSlider.Value),...
                    'Contrast',floor(app.ContrastSlider.Value), 'Saturation',floor(app.SaturationSlider.Value),'Sharpness',floor(app.SharpnessSlider.Value),...
                    'ExposureCompensation',floor(app.ExposureCompensationSlider.Value),'HorizontalFlip',str2double(app.HorizontalFlipSwitch.Value),...
                    'VerticalFlip',str2double(app.VerticalFlipSwitch.Value),'Rotation',str2double(app.RotationDropDown.Value),'ExposureMode',app.ExposureModeDropDown.Value,...
                    'AWBMode',app.AWBModeDropDown.Value,'MeteringMode',app.MeteringModeDropDown.Value);
            end
        end
        
        function startFeed(app,~,~)
            createCameraObj(app);
            if isa(app.CameraObj,'raspi.internal.CameraBoard')
                app.Image = snapshot(app.CameraObj);
                imshow(app.Image,"Parent",app.UIAxes);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(timerfindall);
            hb = codertarget.linux.remotebuild.BoardParameters('Raspberry Pi');
            hb.getParam('hostname');
            if isempty(hb.getParam('hostname'))
                error("Create raspi object in command window and clear");
            end

            ssh = matlabshared.internal.ssh2client(hb.getParam('hostname'), hb.getParam('username'), hb.getParam('password'));
            execute(ssh,'sudo pkill mwUnifiedSrvr');

            app.RaspiObj = raspi();
            app.CameraObj = cameraboard(app.RaspiObj);

            app.StopFeedButton.Enable = 'off';
            app.StartFeedButton.Enable = 'on';
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            createCameraObj(app);
            app.Image = snapshot(app.CameraObj);
            imshow(app.Image,"Parent",app.UIAxes);
        end

        % Button pushed function: StartFeedButton
        function StartFeedButtonPushed(app, event)
            app.StopFeedButton.Enable = 'on';
            app.StartFeedButton.Enable = 'off';
            delete(timerfindall);            
            app.TimerObj = timer('Period',5,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.TimerObj.TimerFcn = @(~,~)app.startFeed;
            start(app.TimerObj);
        end

        % Button pushed function: StopFeedButton
        function StopFeedButtonPushed(app, event)
            app.StopFeedButton.Enable = 'off';
            app.StartFeedButton.Enable = 'on';
            stop(app.TimerObj);
            delete(app.TimerObj);
            delete(timerfindall);
        end

        % Value changed function: AWBModeDropDown, BrightnessSlider, 
        % ContrastSlider, ExposureCompensationSlider, 
        % ExposureModeDropDown, HorizontalFlipSwitch, 
        % MeteringModeDropDown, QualitySlider, RotationDropDown, 
        % SaturationSlider, SharpnessSlider, VerticalFlipSwitch
        function QualitySliderValueChanged(app, event)
            createCameraObj(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CameraConfigurationPanel = uipanel(app.UIFigure);
            app.CameraConfigurationPanel.Title = 'Camera Configuration Panel';
            app.QualitySliderLabel = uilabel(app.CameraConfigurationPanel);
            app.QualitySliderLabel.Text = 'Quality';
            app.QualitySlider = uislider(app.CameraConfigurationPanel);
            app.QualitySlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.QualitySlider.Value = 10;
            app.RotationDropDownLabel = uilabel(app.CameraConfigurationPanel);
            app.RotationDropDownLabel.Text = 'Rotation';
            app.RotationDropDown = uidropdown(app.CameraConfigurationPanel);
            app.RotationDropDown.Items = {'0', '90', '180', '270'};
            app.RotationDropDown.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.RotationDropDown.Value = '0';
            app.HorizontalFlipSwitchLabel = uilabel(app.CameraConfigurationPanel);
            app.HorizontalFlipSwitchLabel.Text = 'Horizontal Flip';
            app.HorizontalFlipSwitch = uiswitch(app.CameraConfigurationPanel, 'slider');
            app.HorizontalFlipSwitch.Items = {'1', '0'};
            app.HorizontalFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.HorizontalFlipSwitch.Value = '0';
            app.BrightnessSliderLabel = uilabel(app.CameraConfigurationPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.CameraConfigurationPanel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.BrightnessSlider.Value = 50;
            app.ContrastSliderLabel = uilabel(app.CameraConfigurationPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CameraConfigurationPanel);
            app.ContrastSlider.Limits = [-100 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.ContrastSlider.Value = 10;
            app.SaturationSliderLabel = uilabel(app.CameraConfigurationPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.CameraConfigurationPanel);
            app.SaturationSlider.Limits = [-100 100];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.SaturationSlider.Value = 10;
            app.SharpnessSliderLabel = uilabel(app.CameraConfigurationPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.CameraConfigurationPanel);
            app.SharpnessSlider.Limits = [-100 100];
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.SharpnessSlider.Value = 10;
            app.VerticalFlipSwitchLabel = uilabel(app.CameraConfigurationPanel);
            app.VerticalFlipSwitchLabel.Text = 'Vertical Flip';
            app.VerticalFlipSwitch = uiswitch(app.CameraConfigurationPanel, 'slider');
            app.VerticalFlipSwitch.Items = {'0', '1'};
            app.VerticalFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.VerticalFlipSwitch.Value = '0';
            app.ExposureCompensationSliderLabel = uilabel(app.CameraConfigurationPanel);
            app.ExposureCompensationSliderLabel.Text = {'Exposure '; 'Compensation '};
            app.ExposureCompensationSlider = uislider(app.CameraConfigurationPanel);
            app.ExposureCompensationSlider.Limits = [-10 10];
            app.ExposureCompensationSlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.ExposureCompensationSlider.Value = 10;
            app.ExposureModeLabel = uilabel(app.CameraConfigurationPanel);
            app.ExposureModeLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.CameraConfigurationPanel);
            app.ExposureModeDropDown.Items = {'auto', 'night', 'nightpreview', 'backlight', 'spotlight', 'sports', 'snow', 'beach', 'verylong', 'fixedfps', 'antishake', 'fireworks'};
            app.ExposureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.ExposureModeDropDown.Value = 'auto';
            app.AWBModeLabel = uilabel(app.CameraConfigurationPanel);
            app.AWBModeLabel.Text = 'AWB Mode';
            app.AWBModeDropDown = uidropdown(app.CameraConfigurationPanel);
            app.AWBModeDropDown.Items = {'auto', 'off', 'sun', 'cloud', 'shade', 'tungsten', 'fluorescen', 'incandescen', 'flash', 'horizon'};
            app.AWBModeDropDown.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.AWBModeDropDown.Value = 'auto';
            app.MeteringModeLabel = uilabel(app.CameraConfigurationPanel);
            app.MeteringModeLabel.Text = 'Metering Mode ';
            app.MeteringModeDropDown = uidropdown(app.CameraConfigurationPanel);
            app.MeteringModeDropDown.Items = {'average', 'spot', 'backlit', 'matrix'};
            app.MeteringModeDropDown.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.MeteringModeDropDown.Value = 'average';
            app.ImageAnalysisPanel = uipanel(app.UIFigure);
            app.ImageAnalysisPanel.Title = 'Image Analysis Panel';
            app.UIAxes = uiaxes(app.ImageAnalysisPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CaptureButton = uibutton(app.ImageAnalysisPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.AnalyseButton = uibutton(app.ImageAnalysisPanel, 'push');
            app.AnalyseButton.Text = 'Analyse';
            app.EmailButton = uibutton(app.ImageAnalysisPanel, 'push');
            app.EmailButton.Text = 'Email';
            app.ResultsPanel = uipanel(app.ImageAnalysisPanel);
            app.ResultsPanel.Title = 'Results Panel';
            app.RBCCountEditFieldLabel = uilabel(app.ResultsPanel);
            app.RBCCountEditFieldLabel.Text = 'RBC Count';
            app.RBCCountEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.WBCCountEditFieldLabel = uilabel(app.ResultsPanel);
            app.WBCCountEditFieldLabel.Text = 'WBC Count';
            app.WBCCountEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.PlatletsCountEditFieldLabel = uilabel(app.ResultsPanel);
            app.PlatletsCountEditFieldLabel.Text = 'Platlets Count';
            app.PlatletsCountEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.StartFeedButton = uibutton(app.ImageAnalysisPanel, 'push');
            app.StartFeedButton.ButtonPushedFcn = createCallbackFcn(app, @StartFeedButtonPushed, true);
            app.StartFeedButton.Text = 'Start Feed';
            app.StopFeedButton = uibutton(app.ImageAnalysisPanel, 'push');
            app.StopFeedButton.ButtonPushedFcn = createCallbackFcn(app, @StopFeedButtonPushed, true);
            app.StopFeedButton.Text = 'Stop Feed';
            app.PatientInformationPanel = uipanel(app.UIFigure);
            app.PatientInformationPanel.Title = 'Patient Information Panel';
            app.NameEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.AgeEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PatientInformationPanel, 'numeric');
            app.SexDropDownLabel = uilabel(app.PatientInformationPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.PatientInformationPanel);
            app.SexDropDown.Items = {'Male', 'Female', 'Other'};
            app.SexDropDown.Value = 'Male';
            app.DateDatePickerLabel = uilabel(app.PatientInformationPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.PatientInformationPanel);
            app.DateDatePicker.Value = datetime([2022 12 20]);
            app.EmailIDEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.EmailIDEditFieldLabel.Text = 'Email ID';
            app.EmailIDEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Determination of Blood Components (WBCs, RBCs, and Platelets) Count in Microscopic Images Using Image Processing and Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end