classdef myapp_webcam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        PatientInformationPanel         matlab.ui.container.Panel
        EmailIDEditField                matlab.ui.control.EditField
        EmailIDEditFieldLabel           matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ImageAnalysisPanel              matlab.ui.container.Panel
        StatusPanel                     matlab.ui.container.Panel
        DangerLamp                      matlab.ui.control.Lamp
        DangerLampLabel                 matlab.ui.control.Label
        NormalLamp                      matlab.ui.control.Lamp
        NormalLampLabel                 matlab.ui.control.Label
        AnalysePanel                    matlab.ui.container.Panel
        EmailButton                     matlab.ui.control.Button
        AnalyseButton                   matlab.ui.control.Button
        FromFilePanel                   matlab.ui.container.Panel
        LoadImageButton                 matlab.ui.control.Button
        FromHardwarePanel               matlab.ui.container.Panel
        CaptureButton                   matlab.ui.control.Button
        StopFeedButton                  matlab.ui.control.Button
        StartFeedButton                 matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        Lens2MaginificationfactorxDropDown  matlab.ui.control.DropDown
        Lens2MaginificationfactorxDropDownLabel  matlab.ui.control.Label
        Bloodquantitymm3EditField       matlab.ui.control.NumericEditField
        Bloodquantitymm3EditFieldLabel  matlab.ui.control.Label
        Areaoftheglassmm22575EditField  matlab.ui.control.NumericEditField
        Areaoftheglassmm22575EditFieldLabel  matlab.ui.control.Label
        Lens1MaginificationfactorxDropDown  matlab.ui.control.DropDown
        Lens1MaginificationfactorxDropDownLabel  matlab.ui.control.Label
        Platlets152361thousandmm3EditField  matlab.ui.control.NumericEditField
        Platlets152361thousandmm3EditFieldLabel  matlab.ui.control.Label
        WBC38104thousandmm3EditField    matlab.ui.control.NumericEditField
        WBC38104thousandmm3EditFieldLabel  matlab.ui.control.Label
        RBC38569millionmm3EditField     matlab.ui.control.NumericEditField
        RBC38569millionmm3EditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        EmailID
        Image
        RaspiObj
        CameraObj
        TimerObj
    end

    methods (Access = public)

        function createCameraObj(app)
            if isempty(app.CameraObj)
                app.CameraObj = webcam(app.RaspiObj,1,'640x480');
            end
        end
        
        function startFeed(app,~,~)
            app.Image = snapshot(app.CameraObj);
            imshow(app.Image,"Parent",app.UIAxes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(timerfindall);
            hb = codertarget.linux.remotebuild.BoardParameters('Raspberry Pi');
            hb.getParam('hostname');
            if isempty(hb.getParam('hostname'))
                error("Create raspi object in command window and clear");
            end

             app.RaspiObj = raspi();
             app.createCameraObj()

            app.StopFeedButton.Enable = 'off';
            app.StartFeedButton.Enable = 'on';
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            app.Image = snapshot(app.CameraObj);
            imshow(app.Image,"Parent",app.UIAxes);
        end

        % Button pushed function: StartFeedButton
        function StartFeedButtonPushed(app, event)
            app.StopFeedButton.Enable = 'on';
            app.StartFeedButton.Enable = 'off';
            delete(timerfindall);            
            app.TimerObj = timer('Period',1,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.TimerObj.TimerFcn = @(~,~)app.startFeed;
            start(app.TimerObj);
        end

        % Button pushed function: StopFeedButton
        function StopFeedButtonPushed(app, event)
            app.StopFeedButton.Enable = 'off';
            app.StartFeedButton.Enable = 'on';
            stop(app.TimerObj);
            delete(app.TimerObj);
            delete(timerfindall);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.Image = imread(uigetfile('*.*'));
            imshow(app.Image,"Parent",app.UIAxes);
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            [rbc,platlets,wbc] =  countComponents(app.Image);  
            app.RBC38569millionmm3EditField.Value = 0.5*rbc*str2double(app.Lens1MaginificationfactorxDropDown.Value)*str2double(app.Lens2MaginificationfactorxDropDown.Value)*app.Areaoftheglassmm22575EditField.Value;
            app.Platlets152361thousandmm3EditField.Value = 3*platlets*str2double(app.Lens1MaginificationfactorxDropDown.Value)*str2double(app.Lens2MaginificationfactorxDropDown.Value)*app.Areaoftheglassmm22575EditField.Value/app.Bloodquantitymm3EditField.Value;
            app.WBC38104thousandmm3EditField.Value = 2*wbc*str2double(app.Lens1MaginificationfactorxDropDown.Value)*str2double(app.Lens2MaginificationfactorxDropDown.Value)*app.Areaoftheglassmm22575EditField.Value/app.Bloodquantitymm3EditField.Value;

            if (app.RBC38569millionmm3EditField.Value >= 3.8e+07 && app.RBC38569millionmm3EditField.Value <= 5.69e+07) && ...
                    (app.Platlets152361thousandmm3EditField.Value >= 1.52e+05 && app.Platlets152361thousandmm3EditField.Value <= 3.61e+05) && ...
                    (app.WBC38104thousandmm3EditField.Value >= 3.8e+04 && app.WBC38104thousandmm3EditField.Value <= 10.4e+04) 
    
                    app.NormalLamp.Color = [0 1 0];
                    app.DangerLamp.Color = [0.65 0.65 0.65];
            else
                    app.DangerLamp.Color = [1 0 0];
                    app.NormalLamp.Color = [0.65 0.65 0.65];

            end
                 

        end

        % Button pushed function: EmailButton
        function EmailButtonPushed(app, event)
            exportapp(app.UIFigure,'results.jpg')
            sendEmailResults(app.EmailIDEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAnalysisPanel = uipanel(app.UIFigure);
            app.ImageAnalysisPanel.Title = 'Image Analysis Panel';
            app.UIAxes = uiaxes(app.ImageAnalysisPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ResultsPanel = uipanel(app.ImageAnalysisPanel);
            app.ResultsPanel.Title = 'Results Panel';
            app.RBC38569millionmm3EditFieldLabel = uilabel(app.ResultsPanel);
            app.RBC38569millionmm3EditFieldLabel.Text = 'RBC  (3.8-5.69 million/mm3)';
            app.RBC38569millionmm3EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.WBC38104thousandmm3EditFieldLabel = uilabel(app.ResultsPanel);
            app.WBC38104thousandmm3EditFieldLabel.Text = 'WBC  (3.8 -10.4 thousand/mm3)';
            app.WBC38104thousandmm3EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Platlets152361thousandmm3EditFieldLabel = uilabel(app.ResultsPanel);
            app.Platlets152361thousandmm3EditFieldLabel.Text = 'Platlets (152-361 thousand/mm3)';
            app.Platlets152361thousandmm3EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Lens1MaginificationfactorxDropDownLabel = uilabel(app.ResultsPanel);
            app.Lens1MaginificationfactorxDropDownLabel.Text = 'Lens-1  Maginification factor (x)';
            app.Lens1MaginificationfactorxDropDown = uidropdown(app.ResultsPanel);
            app.Lens1MaginificationfactorxDropDown.Items = {'10', '15'};
            app.Lens1MaginificationfactorxDropDown.Value = '15';
            app.Areaoftheglassmm22575EditFieldLabel = uilabel(app.ResultsPanel);
            app.Areaoftheglassmm22575EditFieldLabel.Text = 'Area of the glass (mm2) (25*75)';
            app.Areaoftheglassmm22575EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Areaoftheglassmm22575EditField.Value = 1875;
            app.Bloodquantitymm3EditFieldLabel = uilabel(app.ResultsPanel);
            app.Bloodquantitymm3EditFieldLabel.Text = 'Blood quantity (mm3)';
            app.Bloodquantitymm3EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Bloodquantitymm3EditField.Value = 1000;
            app.Lens2MaginificationfactorxDropDownLabel = uilabel(app.ResultsPanel);
            app.Lens2MaginificationfactorxDropDownLabel.Text = 'Lens-2  Maginification factor (x)';
            app.Lens2MaginificationfactorxDropDown = uidropdown(app.ResultsPanel);
            app.Lens2MaginificationfactorxDropDown.Items = {'10', '15', '45', ''};
            app.Lens2MaginificationfactorxDropDown.Value = '45';
            app.FromHardwarePanel = uipanel(app.ImageAnalysisPanel);
            app.FromHardwarePanel.Title = 'From Hardware';
            app.StartFeedButton = uibutton(app.FromHardwarePanel, 'push');
            app.StartFeedButton.ButtonPushedFcn = createCallbackFcn(app, @StartFeedButtonPushed, true);
            app.StartFeedButton.Text = 'Start Feed';
            app.StopFeedButton = uibutton(app.FromHardwarePanel, 'push');
            app.StopFeedButton.ButtonPushedFcn = createCallbackFcn(app, @StopFeedButtonPushed, true);
            app.StopFeedButton.Text = 'Stop Feed';
            app.CaptureButton = uibutton(app.FromHardwarePanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.FromFilePanel = uipanel(app.ImageAnalysisPanel);
            app.FromFilePanel.Title = 'From File';
            app.LoadImageButton = uibutton(app.FromFilePanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.AnalysePanel = uipanel(app.ImageAnalysisPanel);
            app.AnalysePanel.Title = 'Analyse';
            app.AnalyseButton = uibutton(app.AnalysePanel, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.EmailButton = uibutton(app.AnalysePanel, 'push');
            app.EmailButton.ButtonPushedFcn = createCallbackFcn(app, @EmailButtonPushed, true);
            app.EmailButton.Text = 'Email';
            app.StatusPanel = uipanel(app.ImageAnalysisPanel);
            app.StatusPanel.Title = 'Status';
            app.NormalLampLabel = uilabel(app.StatusPanel);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.StatusPanel);
            app.DangerLampLabel = uilabel(app.StatusPanel);
            app.DangerLampLabel.Text = 'Danger';
            app.DangerLamp = uilamp(app.StatusPanel);
            app.PatientInformationPanel = uipanel(app.UIFigure);
            app.PatientInformationPanel.Title = 'Patient Information Panel';
            app.NameEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.AgeEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PatientInformationPanel, 'numeric');
            app.SexDropDownLabel = uilabel(app.PatientInformationPanel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.PatientInformationPanel);
            app.SexDropDown.Items = {'Male', 'Female', 'Other'};
            app.SexDropDown.Value = 'Male';
            app.DateDatePickerLabel = uilabel(app.PatientInformationPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.PatientInformationPanel);
            app.DateDatePicker.Value = datetime([2022 12 20]);
            app.EmailIDEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.EmailIDEditFieldLabel.Text = 'Email ID';
            app.EmailIDEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Determination of Blood Components (WBCs, RBCs, and Platelets) Count in Microscopic Images Using Raspberry Pi and USB Webcam';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp_webcam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end